classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        Lamp                       matlab.ui.control.Lamp
        DropDown                   matlab.ui.control.DropDown
        Switch_5                   matlab.ui.control.Switch
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        Image                      matlab.ui.control.Image
        Panel_2                    matlab.ui.container.Panel
        DCheckBox                  matlab.ui.control.CheckBox
        StartBScandetectionButton  matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        PolyfitdegreeSlider        matlab.ui.control.Slider
        PolyfitdegreeSliderLabel   matlab.ui.control.Label
        Switch_4                   matlab.ui.control.Switch
        Switch_3                   matlab.ui.control.Switch
        Switch_2                   matlab.ui.control.Switch
        RightPanel                 matlab.ui.container.Panel
        DiameterLabel              matlab.ui.control.Label
        ManualselectionButton      matlab.ui.control.Button
        Label_4                    matlab.ui.control.Label
        BScanSlider                matlab.ui.control.Slider
        BScanSliderLabel           matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        ShowDropDown               matlab.ui.control.DropDown
        ShowDropDownLabel          matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        bscans_in_table = 1;
        mscancut_length = 0;
        ascan_length = 512;
        mscancut = [];
        mscancut_without_artifacts = []
        mscancut_medfilt = [];
        mscancut_medfilt_imguided = [];
        mscancut_medfilt_imguided_contrast = [];
        mscancut_medfilt_imguided_contrast_gauss = [];
        mscancut_medfilt_imguided_contrast_gauss_derived = [];
        curr_plot = [];
        bscans = [];
        audio = 1;
        bscan_polar = [];
        diameters = [];
    end

    
    methods (Access = private)   
        function processed = processraw(app,file,Offset,Chirp)
                try
                    % Load raw data
                    length = 1024;
                    fileID = fopen(file);
                    A=fread(fileID,[length,inf],'uint16');
                    A=A*540;
                catch ME
                    error(app,'Unable to load file for preprocessing!');
                end
                try
                    % Substract Offset
                	A=A-Offset;    
                catch ME
                    error(app,'Unable to substract offset!');
                end
                try
                    % Remove DC-Term
                    % Mean of column
                    A =  A - mean(A,2);
                catch ME
                    error(app,'Unable to remove DC-Term!');
                end
                try
                    % Interpolation
                    A=interp1(Chirp,A,0:length-1);   
                catch ME
                    error(app,'Unable to perform interpolation!');
                end
                try
                    % Hann window
                    hw = hann(length);
                    % Multiply elements not matrix multiplication!
                    A = A.*hw;
                catch ME
                    error(app, 'Unable to perform Hann function!');
                end
                try
                    % Fourier
                    A=abs(fft(A));
                    A=20*log(A);
                    % Increase brightness
                    A=fix(A*1.069);
                catch ME
                    error(app, 'Unable to perform Fourier-Transformation!');
                end
                processed=A(1:512,:);
        end
        function mscancut = removeArtifacts(app)
            mscancut = app.mscancut;
            % Remove catheter artifacts
            mscancut(1:111,:) = mean(mscancut,'all');
            % Remove static artifact
            mscancut(221:224,:) = mean(mscancut,'all');
        end    
        function err = error(app,error_message)
            % Function to print error messages in GUI
            app.Label_3.Visible = true;
            app.Label_3.Text = error_message;
            % Shows message for 3 seconds
            pause(3);
            app.Label_3.Visible = false;
            app.Label_3.Text = '';
        end
        function im = imguidedFilterApply(app)
            % Defines section size to apply imguided on higher performance
            sectionsize = 30000;
            c=1;
            while(1)
                % If last section is smaller than 30000 then create section
                % for remaining part
                if(c+sectionsize>app.mscancut_length)
                    app.mscancut_medfilt_imguided(:,c:c+(app.mscancut_length-c)) = imguidedfilter(app.mscancut_medfilt(:,c:c+(app.mscancut_length-c)));
                    break
                end
                app.mscancut_medfilt_imguided(:,c:c+sectionsize) = imguidedfilter(app.mscancut_medfilt(:,c:c+sectionsize));
                c = c+sectionsize;
            end
        end
        function highContrast(app)
            % Determine mean value of image
            matmean = double(mean(app.mscancut_medfilt_imguided,'all'));
            [l,b] = size(app.mscancut_medfilt_imguided);
            app.mscancut_medfilt_imguided_contrast = zeros(l,b);
            % Increase brightness if pixel is greater than mean value + 14
            app.mscancut_medfilt_imguided_contrast(app.mscancut_medfilt_imguided>matmean+14) = app.mscancut_medfilt_imguided(app.mscancut_medfilt_imguided>matmean+14)+ 180;
            % Decrease otherwise
            app.mscancut_medfilt_imguided_contrast(app.mscancut_medfilt_imguided<=matmean+14) = app.mscancut_medfilt_imguided(app.mscancut_medfilt_imguided<=matmean+14)- 100;
        end
        function derived = image_derivative(app,image)
            % Columnwise substract pixel from its neighbor
            % For edge detection
            derived = image;
            [row,col] = size(app.mscancut);
            for x = 1:row*col-1
                derived(x) = image(x+1)-image(x);
            end
        end
        function g = generateImages(app)
            % Applies different filter to mscan and shows process in
            % waitbar
            f = waitbar(0,'1','Name', 'Removing Artifacts...','CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
            setappdata(f,'canceling',0);
            steps = 6;
            app.mscancut_without_artifacts = removeArtifacts(app);
            
            waitbar(1/steps,f,sprintf('Applying Medfilt...'))
            app.mscancut_medfilt = medfilt2(app.mscancut_without_artifacts,[2 2]);

            waitbar(2/steps,f,sprintf('Applying Imguided...'))
            imguidedFilterApply(app);

            waitbar(3/steps,f,sprintf('Increasing Contrast...'))
            highContrast(app);

            waitbar(4/steps,f,sprintf('Applying Gauss...'))
            app.mscancut_medfilt_imguided_contrast_gauss = conv2(app.mscancut_medfilt_imguided_contrast,fspecial('gaussian', [2 2], 1));

            waitbar(5/steps,f,sprintf('Deriving Image...'))
            app.mscancut_medfilt_imguided_contrast_gauss_derived = zeros(app.ascan_length,app.mscancut_length);
            app.mscancut_medfilt_imguided_contrast_gauss_derived = image_derivative(app,image_derivative(app,app.mscancut_medfilt_imguided_contrast_gauss));

            waitbar(6/steps,f,sprintf('Finishing...'))
            pause(1);
            delete(f);
            app.audio = 0;
        end
        function imageBscan(app,bscan)
            % Shows image in UIAxes2
            app.UIAxes2.XScale
            hold(app.UIAxes2,"off");
            image(app.UIAxes2,bscan)
            hold(app.UIAxes2,"on");
            app.UIAxes2.Colormap = gray;
        end
        function im = pol2cart_Image(~,image)
            % casts matrix type to double
            image = double(image);
            [h,w,~] = size(image);
            % duplicates the generated linear vector from -pi to pi
            [theta,rho] = meshgrid(linspace(-pi,pi,w), 1:h);
            % creates the 1024x1024 coordinate grid for the transformation
            [x1,y1] = meshgrid(-h:h,-h:h);
            % transforms corresponding elements of the two-dimensional Cartesian coordinate arrays x and y into polar coordinates theta and rho
            [x,y] = cart2pol(-x1,y1);
            % Interpolation to fill space between ascans
            im = interp2(theta,rho,image,x,y);
        end
        function initializeBscanSelection(app)
            % adds detected bscans to drop down menu
            items = {};
            for count = 1:size(app.bscans,2)
                items{end+1} = strcat('bscan ',int2str(count));
            end
            app.ShowDropDown.Items = items;
        end
        function playSound(app)
            % very important waiting sound!!!
            % enable for the best experience
            load('waiting.mat');
            Fs = 10000;
            while(1)
               sound(y,Fs);
               pause(3);
            end
        end
        function [endPos,bscan,diameter] = getPolyBscan(app,bscan)
            % Curve sampling
            [ascan_length,bscan_length] = size(bscan);
            for y = 1:bscan_length
                for x = 1:ascan_length % Goes through ascan
                    if bscan(x,y) > 100 % Finds first occurence of white point/line point
                        bscan(x,y) = 250;
                        bscan(x+1:ascan_length,y) = 0;
                        % Collect points
                        points_X(y) = x;
                        points_Y(y) = y;
                    end
                end
            end
            [~,amount_of_points] = size(points_Y);
            range_neighborhood = 300;
            for x = 1:amount_of_points
                if points_Y(x) ~= 0
                    curr = x; 
                    for y = curr+1:amount_of_points
                        if points_Y(y) ~= 0
                            next = y; 
                            height_distance = abs(points_X(curr)-points_X(next));
                            distance = next - curr;
                            if (height_distance > distance/2 || height_distance > 25) && distance < range_neighborhood
                                % Points within the neighborhood that have a 
                                % height distance of more than 25 or more than 
                                % half the x-distance to their predecessor are 
                                % removed
                                points_X(next) = 0;
                                points_Y(next) = 0;
                           else
                                break
                           end 
                        end
                    end
                end
            end
        
            x_tmp = [];
            y_tmp = [];
            % Removing points in origin
            % Reason: we placed all points that have to be removed there
            % temporarily
            for c = 1:amount_of_points
                if points_X(c) ~= 0 && points_Y(c) ~= 0
                    % Keep points unequal origin
                    x_tmp(end+1) = points_X(c);
                    y_tmp(end+1) = points_Y(c);
                end
            end
            points_X = x_tmp;
            points_Y = y_tmp;
            
            % Approximation
            % p: coefficients for polynomial
            % mu: two-element vector with centering and scaling values
            degree = 25;
            [p,~,mu] = polyfit(points_Y, points_X, degree);
            % evaluates the polynomial p at each point in points_Y
            f = polyval(p,points_Y,[],mu);
            
            % Calculate diameter
            radius = mean(f) - 104;
            refractive_index = 1.33/1000;
            dist = 5.2;
            diameter = 2*(radius*refractive_index*dist + 0.45);
            
            % Interpolation
            for c = 1:bscan_length
                f_new(c) = interp1(points_Y,f,c);
            end
            f = f_new; 
    
            points_Y = 1:bscan_length;
            
            % Point to start searching for local minima
            endPos = size(bscan,2) - fix(0.2*size(bscan,2));
            % Find local minima
            for c = size(bscan,2) - fix(0.2*size(bscan,2)):bscan_length
                if f(endPos) < f(c)
                    endPos = c;
                end
            end
            
            % Transform into image
            bscan_new = zeros(ascan_length,endPos);
            for c = 2:endPos
                if fix(-f(c)+ascan_length*(c-1)) > 0
                    bscan_new(fix(-f(c)+ascan_length*(c-1))) = 200;
                end
            end
            bscan = imrotate(bscan_new,180);
            bscan = flipdim(bscan,2);
           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.UIFigure.WindowState = 'maximized';
            axis(app.UIAxes,'off');
            axis(app.UIAxes2,'off');
        end

        % Callback function
        function LoadFileButtonPushed(app, event)

        end

        % Callback function
        function HTMLDataChanged(app, event)
            data = app.HTML.Data;
            app.LoadFileButton.Text = 'clicked';
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            if(app.Switch.Value == 'Preprocessing')
                [file,path] = uigetfile('*.dat');
                File = fullfile(path, file);
                [fPath, fName, fExt] = fileparts(file);
                if(lower(fExt) == '.dat')
                    try
                        Offset_Chirp_File = fullfile(path, 'offset_chirp.mat');
                        off = load(Offset_Chirp_File);
                        try
                            cla(app.UIAxes);
                            app.Label_2.Text = strcat('Loaded Raw: ',fName);
                            y = 'here'
                            %error(app,app.processraw(File,off.Offset,1024));
                            app.mscancut = processraw(app,File,off.Offset,off.Chirp);
                            app.mscancut_length = size(app.mscancut,2);
                            hold(app.UIAxes,"off");
                            image(app.UIAxes,app.mscancut)
                            hold(app.UIAxes,"on");
                            app.UIAxes.Colormap = gray;
                            generateImages(app)
                            app.Lamp.Color = [0 1 0];
                            app.Switch_5.Enable = true;
                            app.BScanSlider.Enable = true;
                            app.StartBScandetectionButton.Enable = true;
                        catch ME
                            error(app, 'Error: Processing data failed!')
                        end
                    catch ME
                        error(app,'offset_chirp.mat not found in folder!')
                    end
                else
                    error(app,'Wrong File!')
                end
            else
                [file,path] = uigetfile('*.mat');
                File = fullfile(path, file);
                var = load(File);
                app.Label_2.Text = strcat('Loaded Raw: ',file);
                app.mscancut = var.mscancut;
                app.mscancut_length = size(var.mscancut,2);
                hold(app.UIAxes,"off");
                image(app.UIAxes,var.mscancut)
                hold(app.UIAxes,"on");
                app.UIAxes.Colormap = gray;
                %uncomment for sound
                %p = parpool(2);
                %par = parfeval(@playSound,0,app);
                generateImages(app);
                app.Lamp.Color = [0 1 0];
                %cancel(par);
                app.Switch_5.Enable = true;
                app.BScanSlider.Enable = true;
                app.StartBScandetectionButton.Enable = true;
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            % Changes color of preprocessing switch to green
            if(value == 'Preprocessing')
                app.Switch.FontColor = [0.00,1.00,0.00]
            else
                app.Switch.FontColor = [1.00,1.00,1.00]
            end
        end

        % Button pushed function: ManualselectionButton
        function ManualselectionButtonPushed(app, event)
            % Button to set your own bscan
            prompt = {'Enter begin:','Enter end:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'0','0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            %app.UITable.Data = {strcat('bscan ', int2str(app.bscans_in_table)),answer{1},answer{2}};
            %app.bscans_in_table = app.bscans_in_table + 1;
            imageBscan(app,app.mscancut_medfilt(:,str2num(answer{1}):str2num(answer{2})));
        end

        % Value changing function: BScanSlider
        function BScanSliderValueChanging(app, event)
            % Slider to select end parameter of first bscan
            changingValue = event.Value;
            if (app.curr_plot == [])
                app.Label_4.Text = int2str(changingValue*4100.00+1);
                app.curr_plot = plot(app.UIAxes,[changingValue*4100.00+1,changingValue*4100.00+1],[1,512],'Color','w','LineWidth',2);
            else
                delete(app.curr_plot);
                app.Label_4.Text = int2str(changingValue*4100.00+1);
                app.curr_plot = plot(app.UIAxes,[changingValue*4100.00+1,changingValue*4100.00+1],[1,512],'Color','w','LineWidth',2);
            end

            %hold(app.UIAxes,"off");
            %hold(app.UIAxes,"off")
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
            value = app.Switch_5.Value;
            if(value == 'Apply Filter')
                hold(app.UIAxes,"off");
                switch app.DropDown.Value
                    case 'Imguided'
                        image(app.UIAxes,app.mscancut_medfilt_imguided);                        
                    case 'Contrast'
                        image(app.UIAxes,app.mscancut_medfilt_imguided_contrast);                        
                    case 'Gauss'
                        image(app.UIAxes,app.mscancut_medfilt_imguided_contrast_gauss);                        
                    case 'Derivation'
                        image(app.UIAxes,app.mscancut_medfilt_imguided_contrast_gauss_derived);     
                end
                hold(app.UIAxes,"on");
            else
                hold(app.UIAxes,"off");
                image(app.UIAxes,app.mscancut);
                hold(app.UIAxes,"on");
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if(app.Switch_5.Value == 'Apply Filter')
                hold(app.UIAxes,"off");
                switch app.DropDown.Value
                    case 'Imguided'
                        image(app.UIAxes,app.mscancut_medfilt_imguided);                        
                    case 'Contrast'
                        image(app.UIAxes,app.mscancut_medfilt_imguided_contrast);                        
                    case 'Gauss'
                        image(app.UIAxes,app.mscancut_medfilt_imguided_contrast_gauss);                        
                    case 'Derivation'
                        image(app.UIAxes,app.mscancut_medfilt_imguided_contrast_gauss_derived);     
                end
                hold(app.UIAxes,"on");
            end
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            value = app.Switch_4.Value;
            if(value == 'Polyfit')
                if(app.Switch_3.Value == 'Polar')
                    val = str2num(app.ShowDropDown.Value(6:end));
                    s = app.bscans(val);
                    bscan_length_approx = str2num(app.Label_4.Text) + fix(0.2*str2num(app.Label_4.Text));
                    bscan_tmp = app.mscancut_medfilt_imguided_contrast_gauss_derived(:,s:s+bscan_length_approx);
                    [pos,scan,dia] = getPolyBscan(app,bscan_tmp);
                    imageBscan(app,pol2cart_Image(app,scan)); 
                else
                    val = str2num(app.ShowDropDown.Value(6:end));
                    s = app.bscans(val);
                    bscan_length_approx = str2num(app.Label_4.Text) + fix(0.2*str2num(app.Label_4.Text));
                    bscan_tmp = app.mscancut_medfilt_imguided_contrast_gauss_derived(:,s:s+bscan_length_approx);
                    [pos,scan,dia] = getPolyBscan(app,bscan_tmp);
                    imageBscan(app,scan);  
                end
            else
                if(app.Switch_3.Value == 'Polar')
                    val = str2num(app.ShowDropDown.Value(6:end));
                    s = app.bscans(val);
                    bscan_length_approx = str2num(app.Label_4.Text) + fix(0.2*str2num(app.Label_4.Text));
                    bscan_tmp = app.mscancut_medfilt_imguided_contrast_gauss_derived(:,s:s+bscan_length_approx);
                    [endpos,scan,dia] = getPolyBscan(app,bscan_tmp);
                    im = app.mscancut_medfilt(:,s:s+endpos);
                    imageBscan(app,pol2cart_Image(app,im)); 
                else
                    val = str2num(app.ShowDropDown.Value(6:end));
                    s = app.bscans(val);
                    bscan_length_approx = str2num(app.Label_4.Text) + fix(0.2*str2num(app.Label_4.Text));
                    bscan_tmp = app.mscancut_medfilt_imguided_contrast_gauss_derived(:,s:s+bscan_length_approx);
                    [endpos,scan,dia] = getPolyBscan(app,bscan_tmp);
                    im = app.mscancut_medfilt(:,s:s+endpos);
                    imageBscan(app,im);  
                end
            end
            

        end

        % Button pushed function: StartBScandetectionButton
        function StartBScandetectionButtonPushed(app, event)
            bscan_length_approx = str2num(app.Label_4.Text) + fix(0.2*str2num(app.Label_4.Text));
            app.bscans = [];
            app.diameters = [];
            app.bscans_in_table = 1;
            app.UITable.Data = {};
            pos = 1;
            %bscans = {};
            count = 1;
            % Goes through bscans until end reached
            % Approximation
            while 1
                if pos+bscan_length_approx > app.mscancut_length
                    break;
                end
                bscan_tmp = app.mscancut_medfilt_imguided_contrast_gauss_derived(:,pos:pos+bscan_length_approx);
                [endPos,bscan,dia] = getPolyBscan(app,bscan_tmp);
                bpos = pos;
                pos = pos + endPos;
                oldData = app.UITable.Data;
                app.bscans(end+1) = bpos;
                app.diameters(end+1) = dia;
                newRow = {strcat('bscan ', int2str(app.bscans_in_table)),bpos,pos,dia};
                app.bscans_in_table = app.bscans_in_table + 1;
                newData = [oldData; newRow];
                app.UITable.Data = newData;
                %bscans = [bscans, bscan];
                count = count + 1;
            end
            initializeBscanSelection(app);
            app.Switch_2.Enable = true;
            app.Switch_3.Enable = true;
            app.Switch_4.Enable = true;
            app.ShowDropDown.Enable = true;
            app.PolyfitdegreeSlider.Enable = true;
        end

        % Value changed function: ShowDropDown
        function ShowDropDownValueChanged(app, event)
            value = app.ShowDropDown.Value;
            val = str2num(value(6:end));
            s = app.bscans(val);
            e = app.bscans(val+1);
            imageBscan(app,app.mscancut_medfilt(:,s:e));
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            if(value == 'Polar')
                if(app.Switch_4.Value == 'Polyfit')
                    val = str2num(app.ShowDropDown.Value(6:end));
                    s = app.bscans(val);
                    bscan_length_approx = str2num(app.Label_4.Text) + fix(0.2*str2num(app.Label_4.Text));
                    bscan_tmp = app.mscancut_medfilt_imguided_contrast_gauss_derived(:,s:s+bscan_length_approx);
                    [pos,scan] = getPolyBscan(app,bscan_tmp);
                    imageBscan(app,pol2cart_Image(app,scan));  
                else
                    val = str2num(app.ShowDropDown.Value(6:end));
                    s = app.bscans(val);
                    e = app.bscans(val+1);
                    imageBscan(app,pol2cart_Image(app,app.mscancut_medfilt(:,s:e)));
                end
            else
                if(app.Switch_4.Value == 'Polyfit')
                    val = str2num(app.ShowDropDown.Value(6:end));
                    s = app.bscans(val);
                    bscan_length_approx = str2num(app.Label_4.Text) + fix(0.2*str2num(app.Label_4.Text));
                    bscan_tmp = app.mscancut_medfilt_imguided_contrast_gauss_derived(:,s:s+bscan_length_approx);
                    [pos,scan,dia] = getPolyBscan(app,bscan_tmp);
                    imageBscan(app,scan);  
                else
                    val = str2num(app.ShowDropDown.Value(6:end));
                    s = app.bscans(val);
                    e = app.bscans(val+1);
                    imageBscan(app,app.mscancut_medfilt(:,s:e));
                end 
            end
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            if(value == 'Cross-section')
                if(app.DCheckBox.Value == 1)
                    diameters = app.diameters/2;
                    x = size(diameters,2);
                    diameters = interp1([1:x],diameters,[1:0.5:x],'pchip');
                    x = size(diameters,2);             
                    t = (0:x)'*2*pi/x;
                    hold(app.UIAxes,"off");
                    colormap(app.UIAxes, turbo);
                    h = surf(app.UIAxes,ones(x+1,1)*[1:x],cos(t)*diameters,sin(t)*diameters);
                    %enableDefaultInteractivity(app.UIAxes);
                else
                    diameters = app.diameters/2;
                    x = size(diameters,2);
                    diameters = interp1([1:x],diameters,[1:0.1:x],'pchip');
                    x = size(diameters,2);             
                    hold(app.UIAxes,"off");            
                    plot(app.UIAxes,[1:x],diameters,'Color','k','LineWidth',2);
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,[1:x],(-1)*diameters,'Color','k','LineWidth',2);
                    hold(app.UIAxes,"on");
                    %disableDefaultInteractivity(app.UIAxes);
                end
            else
                
            end
        end

        % Value changed function: DCheckBox
        function DCheckBoxValueChanged(app, event)

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {600, 600};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {251, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel_2 = uipanel(app.LeftPanel);
            app.Switch_2 = uiswitch(app.Panel_2, 'slider');
            app.Switch_2.Items = {' ', 'Cross-section'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Enable = 'off';
            app.Switch_2.Value = ' ';
            app.Switch_3 = uiswitch(app.Panel_2, 'slider');
            app.Switch_3.Items = {' ', 'Polar'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Enable = 'off';
            app.Switch_3.Value = ' ';
            app.Switch_4 = uiswitch(app.Panel_2, 'slider');
            app.Switch_4.Items = {' ', 'Polyfit'};
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Switch_4.Enable = 'off';
            app.Switch_4.Value = ' ';
            app.PolyfitdegreeSliderLabel = uilabel(app.Panel_2);
            app.PolyfitdegreeSliderLabel.Enable = 'off';
            app.PolyfitdegreeSliderLabel.Text = 'Polyfit degree';
            app.PolyfitdegreeSlider = uislider(app.Panel_2);
            app.PolyfitdegreeSlider.Limits = [0 10];
            app.PolyfitdegreeSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.PolyfitdegreeSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.PolyfitdegreeSlider.Enable = 'off';
            app.PolyfitdegreeSlider.Value = 5;
            app.UITable = uitable(app.Panel_2);
            app.UITable.ColumnName = {'BScan'; 'Start'; 'End'; 'Diameter'};
            app.UITable.RowName = {};
            app.StartBScandetectionButton = uibutton(app.Panel_2, 'push');
            app.StartBScandetectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartBScandetectionButtonPushed, true);
            app.StartBScandetectionButton.Enable = 'off';
            app.StartBScandetectionButton.Text = 'Start B-Scan detection';
            app.DCheckBox = uicheckbox(app.Panel_2);
            app.DCheckBox.ValueChangedFcn = createCallbackFcn(app, @DCheckBoxValueChanged, true);
            app.DCheckBox.Text = '3D';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'button1.png';
            app.Switch = uiswitch(app.LeftPanel, 'slider');
            app.Switch.Items = {' ', 'Preprocessing'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = ' ';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = {'_____________________________'; ''; ''};
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '';
            app.Switch_5 = uiswitch(app.LeftPanel, 'slider');
            app.Switch_5.Items = {' ', 'Apply Filter'};
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.Switch_5.Enable = 'off';
            app.Switch_5.Value = ' ';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'Imguided', 'Contrast', 'Gauss', 'Derivation'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Imguided';
            app.Lamp = uilamp(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'M-Scan')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, {'B-Scan'; ''})
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ShowDropDownLabel = uilabel(app.RightPanel);
            app.ShowDropDownLabel.Enable = 'off';
            app.ShowDropDownLabel.Text = 'Show';
            app.ShowDropDown = uidropdown(app.RightPanel);
            app.ShowDropDown.Items = {};
            app.ShowDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowDropDownValueChanged, true);
            app.ShowDropDown.Enable = 'off';
            app.ShowDropDown.Value = {};
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '';
            app.BScanSliderLabel = uilabel(app.RightPanel);
            app.BScanSliderLabel.Enable = 'off';
            app.BScanSliderLabel.Text = 'B-Scan';
            app.BScanSlider = uislider(app.RightPanel);
            app.BScanSlider.MajorTicks = [4000 8000 12000 16000];
            app.BScanSlider.ValueChangingFcn = createCallbackFcn(app, @BScanSliderValueChanging, true);
            app.BScanSlider.Enable = 'off';
            app.Label_4 = uilabel(app.RightPanel);
            app.Label_4.Text = '';
            app.ManualselectionButton = uibutton(app.RightPanel, 'push');
            app.ManualselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ManualselectionButtonPushed, true);
            app.ManualselectionButton.Enable = 'off';
            app.ManualselectionButton.Text = 'Manual selection';
            app.DiameterLabel = uilabel(app.RightPanel);
            app.DiameterLabel.Visible = 'off';
            app.DiameterLabel.Text = 'Diameter:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end